classdef time_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Image4_2           matlab.ui.control.Image
        Image4             matlab.ui.control.Image
        Date               matlab.ui.control.Label
        DigitalClockLabel  matlab.ui.control.Label
        Image3             matlab.ui.control.Image
        Image_2            matlab.ui.control.Image
        Image              matlab.ui.control.Image
        LoadTimeButton     matlab.ui.control.Button
        TIMELabel          matlab.ui.control.Label
    end

    
    methods (Access = public)
        
        function app = startupFcn(app)
    % Get the screen size
    screenSize = get(groot, 'ScreenSize');
    
    % Set the app's figure window position to the screen size
    app.UIFigure.OuterPosition = screenSize;
end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadTimeButton
        function LoadTimeButtonPushed(app, event)
            for i=1:1000
            currentTime = datestr(now);
            app.TIMELabel.Text = datestr(currentTime, 'HH:MM:SS');
            
            
            currentDate = datetime('now');
            app.Date.Text = datestr(currentDate,'dd/mm/yyyy');
            pause(1)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TIMELabel = uilabel(app.UIFigure);
            app.TIMELabel.Text = 'TIME';
            app.LoadTimeButton = uibutton(app.UIFigure, 'push');
            app.LoadTimeButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTimeButtonPushed, true);
            app.LoadTimeButton.Text = 'Load Time';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'IDF-removebg-preview.png';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = 'IDF_GOC-removebg-preview.png';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'Hatal-removebg-preview.png';
            app.DigitalClockLabel = uilabel(app.UIFigure);
            app.DigitalClockLabel.Text = 'Digital Clock';
            app.Date = uilabel(app.UIFigure);
            app.Date.Text = 'Date';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'pikacho-removebg-preview.png';
            app.Image4_2 = uiimage(app.UIFigure);
            app.Image4_2.ImageSource = 'pikacho-removebg-preview.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = time_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end